classdef IlluminationCorrectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IlluminationCorrectionGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        SelectImage                matlab.ui.control.DropDown
        SelectImageLabel           matlab.ui.control.Label
        ClearButton                matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        Title                      matlab.ui.control.Label
        ChooseMethodLabel          matlab.ui.control.Label
        ChooseMethod               matlab.ui.control.DropDown
        Copyrights                 matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.SelectImage.Items = {'retinal1','retinal2','retinal3','retinal4','corona','nuts','rice'};
                app.SelectImage.ItemsData = {'retinal1.png','retinal2.png','retinal3.png','retinal4.png','corona.png','nuts.png','rice.png'};            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
                  close all;      
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.ChooseMethod.Value == '1'
                Superimpose(app.SelectImage.Value); 
            else
                AdaptiveEnhancement(app.SelectImage.Value);
            end 
        end

        % Value changed function: SelectImage
        function SelectImageValueChanged(app, event)
            app.Image.ImageSource=app.SelectImage.Value;           
        end

        % Value changed function: ChooseMethod
        function ChooseMethodValueChanged(app, event)
            if app.ChooseMethod.Value =='1'
                app.SelectImage.Items = {'retinal1','retinal2','retinal3','retinal4','corona','nuts','rice'};
                app.SelectImage.ItemsData = {'retinal1.png','retinal2.png','retinal3.png','retinal4.png','corona.png','nuts.png','rice.png'}; 
            else
                app.SelectImage.Items = {'Image1','Image2','Image3','Image4','Image5','corona','nuts'};
                app.SelectImage.ItemsData = {'Image1.png','Image2.png','Image3.png','Image4.png','Image5.png','corona.png','nuts.png'};
            end
            app.Image.ImageSource=app.SelectImage.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IlluminationCorrectionGUI = uifigure('Visible', 'off');
            app.IlluminationCorrectionGUI.Name = 'Illumination Correction GUI';
            app.GridLayout = uigridlayout(app.IlluminationCorrectionGUI);
            app.SelectImage = uidropdown(app.GridLayout);
            app.SelectImage.Items = {};
            app.SelectImage.ValueChangedFcn = createCallbackFcn(app, @SelectImageValueChanged, true);
            app.SelectImage.Value = {};
            app.SelectImageLabel = uilabel(app.GridLayout);
            app.SelectImageLabel.Text = 'Select Image :';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Superimpose LPF & Gaussian VS Adaptive Enhancement';
            app.ChooseMethodLabel = uilabel(app.GridLayout);
            app.ChooseMethodLabel.Text = 'Choose Method : ';
            app.ChooseMethod = uidropdown(app.GridLayout);
            app.ChooseMethod.Items = {'Superimpose LPF & Gaussian Filter', 'Adaptive Enhancement'};
            app.ChooseMethod.ItemsData = {'1', '0'};
            app.ChooseMethod.ValueChangedFcn = createCallbackFcn(app, @ChooseMethodValueChanged, true);
            app.ChooseMethod.Value = '1';
            app.Copyrights = uilabel(app.GridLayout);
            app.Copyrights.Text = 'Shay Karadi & Tamir Karamani © ';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'retinal1.png';
            % Show the figure after all components are created
            app.IlluminationCorrectionGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IlluminationCorrectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IlluminationCorrectionGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IlluminationCorrectionGUI)
        end
    end
end